classdef Final_GUI_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FilteredSettingsKnob       matlab.ui.control.DiscreteKnob
        FilteredSettingsKnobLabel  matlab.ui.control.Label
        FilterTypeDropDown         matlab.ui.control.DropDown
        FilterTypeDropDownLabel    matlab.ui.control.Label
        OrderKnob                  matlab.ui.control.DiscreteKnob
        OrderKnobLabel             matlab.ui.control.Label
        Label2                     matlab.ui.control.Label
        RPMLabel                   matlab.ui.control.Label
        BPMLabel                   matlab.ui.control.Label
        LableLabel                 matlab.ui.control.Label
        EditOrderEditField         matlab.ui.control.NumericEditField
        EditOrderEditFieldLabel    matlab.ui.control.Label
        LowpassfcEditField         matlab.ui.control.NumericEditField
        LowpassfcEditFieldLabel    matlab.ui.control.Label
        HighpassfcEditField        matlab.ui.control.NumericEditField
        HighpassfcEditFieldLabel   matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end


     properties (Access = private)
        a 
        f
        f1
        Y
        Y1
        P1
        P2
        P3
        P4
        filteredPlot
        rawPlot
        serialport
        Fs = 8000;
        fs = 5000;
        fc1 = 30;
        fc2 = 200;
        T = 1/5000;
        n = 1;
        m = 1;
        m1 = 1;
        plotWindow = 2000;
        IsRunning = false;
        Timer
        Data
        ffData
        fdata 
        coefficient_1
        coefficient_2
        fc3=30
        bpm
        coefficient_3
        coefficient_4
        orderOfFilter=4;
        end

    methods (Access = private)
        
        function timerCallback = func(app)
            if ~app.IsRunning
            stop(app.Timer);
        end
    end
 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IsRunning=true;
            app.Timer=timer;
            app.Timer.Period=0.1;
            app.Timer.ExecutionMode='fixedRate';
            app.Timer.TimerFcn=@(~,~)app.timerCallback;
            start(app.Timer);

            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.IsRunning = true;
            app.a = serialport("COM7", 19200);
            configureTerminator(app.a, "CR/LF");
            flush(app.a);
            app.a.UserData = struct("Data", [], "Order", 1, "fData", [],"ffData",[]);
            app.IsRunning = true;
            
            while app.a.UserData.Order < 100000
                    app.Data = readline(app.a);
                    app.a.UserData.Data(end+1) = str2double(app.Data);
                    app.a.UserData.Order = app.a.UserData.Order + 1;
                if mod(app.a.UserData.Order, 100) == 0
                    configureCallback(app.a, "off");

                    plot(app.UIAxes, app.a.UserData.Data(max(1,end-app.plotWindow+1):end))
                      [app.coefficient_1, app.coefficient_2] = butter(4, app.fc3/(app.fs/2), 'high');
        app.a.UserData.ffData = filter(app.coefficient_1, app.coefficient_2, app.a.UserData.Data(1:end));
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, [app.fc1/(app.fs/2), app.fc2/(app.fs/2)], 'bandpass');

                    app.a.UserData.fData = filter(app.coefficient_3, app.coefficient_4, app.a.UserData.Data);
                    
                    plot(app.UIAxes_2, app.a.UserData.fData(max(1, end-app.plotWindow+1):end) * 3);
                    drawnow;
                    
                    configureTerminator(app.a, "CR/LF");

                end
                 if mod(app.a.UserData.Order,500)==0
                     configureCallback(app.a, "off");

                     app.Y = fft(app.a.UserData.ffData(end-499+1:end));
                     app.P2 = abs(app.Y / 500); % Use 400 for normalization
                     app.f = app.fs * (0:(length(app.Y)/2))/length(app.Y); % Adjust frequency axis
                     app.P1 = app.P2(1:length(app.Y)/2+1);
                     app.P1(2:end-1) = 2 * app.P1(2:end-1);
                     plot(app.UIAxes_3,app.f,app.P1,'Color','r');
                     set(gca,'ylim',[0,10]);

                     app.Y1 = fft(app.a.UserData.fData(end-499+1:end));
                     app.P3 = abs(app.Y1 / 500); % Use 400 for normalization
                     app.f1 = app.fs * (0:(length(app.Y1)/2))/length(app.Y1); % Adjust frequency axis
                     app.P4 = app.P3(1:length(app.Y1)/2+1);
                     app.P4(2:end-1) = 2 * app.P4(2:end-1);
                     plot(app.UIAxes_4, app.f1,app.P4,'Color','r');
                     set(gca,'ylim',[0,15]);

                     grid on;
                     app.m=app.m+500;
                     configureTerminator(app.a,"CR/LF");
                 end
                 if mod(app.a.UserData.Order, 5000) == 0
                    % Dinamik eşik belirleme
                    signalSegment = app.a.UserData.Data(end-4999+1:end);

    % Sinyali normalize et
    normalizedSegment = (signalSegment - min(signalSegment)) / (max(signalSegment) - min(signalSegment));
    
    % Eşiği daha adaptif hale getir (ortalama + standart sapma)
    threshold = mean(normalizedSegment) + 0.48 * std(normalizedSegment);
    
    % Tepe noktalarını bul (Minimum mesafe ekleyerek gürültüyü azalt)
    [peaks, locs] = findpeaks(normalizedSegment, 'MinPeakHeight', threshold, 'MinPeakDistance', round(app.fs * 0.4));
    
    % BPM Hesaplama (Tepe noktaları arası süreye göre)
    if numel(locs) > 1
        % Eğer tepe noktalarının büyüklüğü 100'den küçükse, BPM hesapla
        validPeaks = peaks(peaks < 100);
        validLocs = locs(peaks < 100);
        
        if numel(validLocs) > 1
            interPeakIntervals = diff(validLocs) / app.fs;  % Peak aralıkları saniyeye çevrilir
            avgInterval = mean(interPeakIntervals);
            app.bpm = 60 / avgInterval;  % BPM Hesaplama
        else
            app.bpm = 0;  % Yetersiz geçerli tepe noktası varsa
        end
    else
        app.bpm = 0;  % Yetersiz tepe noktası varsa
    end
    
    % BPM'i ekrana yazdır
    disp(['Measured BPM: ', num2str(int16(app.bpm))]);
    app.LableLabel.Text = num2str(int16(app.bpm));
end
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.IsRunning=false;
            delete(app.a);
        end

        % Value changed function: LowpassfcEditField
        function LowpassfcEditFieldValueChanged(app, event)
            b=app.LowpassfcEditField.Value;
            app.fc2=b;
        end

        % Value changed function: HighpassfcEditField
        function HighpassfcEditFieldValueChanged(app, event)
             c= app.HighpassfcEditField.Value;
             app.fc1=c;
        end

        % Value changed function: EditOrderEditField
        function EditOrderEditFieldValueChanged(app, event)
            j = app.EditOrderEditField.Value;
            app.orderOfFilter=j;
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            
        end

        % Drop down opening function: FilterTypeDropDown
        function FilterTypeDropDownOpening(app, event)
            
        end

        % Clicked callback: FilterTypeDropDown
        function FilterTypeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            % Slider Value Changed callback function
function SliderValueChanged(app, event)
    sliderValue = app.Slider.Value; % Get the current slider value
    app.fc2 = sliderValue; % Set Lowpass and Bandstop upper cutoff frequency (for example)
    app.fc1 = sliderValue / 2; % Set Highpass lower cutoff frequency (example: slider value is used as reference)
    
    % Filter type selection based on Knob value
    value = app.Knob.Value;  % Knob value to determine filter type
    
    switch value
        case 'Low Pass'
            [b, a] = butter(app.orderOfFilter, app.fc2/(app.fs/2), 'low');
        case 'High Pass'
            [b, a] = butter(app.orderOfFilter, app.fc1/(app.fs/2), 'high');
        case 'Bandstop'
            [b, a] = butter(app.orderOfFilter, [app.fc1 app.fc2]/(app.fs/2), 'stop');
        case 'Bandpass'
            [b, a] = butter(app.orderOfFilter, [app.fc1 app.fc2]/(app.fs/2), 'bandpass');
    end
    
    % Apply filtered signal
    filteredSignal = filter(b, a, app.a.UserData.Data);
    
    % Plot filtered signal
    plot(app.UIAxes_2, filteredSignal);
    title(app.UIAxes_2, ['Filtered Signal - ', value]);
    xlabel(app.UIAxes_2, 'Time');
    ylabel(app.UIAxes_2, 'Amplitude');
    drawnow;
             end
        end
        
        end

        % Value changed function: FilteredSettingsKnob
        function FilteredSettingsKnobValueChanged(app, event)
            value = app.FilteredSettingsKnob.Value;
            
        end

        % Value changed function: OrderKnob
        function OrderKnobValueChanged(app, event)
            value = app.OrderKnob.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 1000];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtered Signal')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YLim = [-1000 1000];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Raw Signal FFT')
            xlabel(app.UIAxes_3, 'Frequency')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Filtered Signal FFT')
            xlabel(app.UIAxes_4, 'Frequency')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HighpassfcEditFieldLabel = uilabel(app.UIFigure);
            app.HighpassfcEditFieldLabel.Text = 'High pass fc';
            app.HighpassfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighpassfcEditField.ValueChangedFcn = createCallbackFcn(app, @HighpassfcEditFieldValueChanged, true);
            app.HighpassfcEditField.Value = 30;
            app.LowpassfcEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassfcEditFieldLabel.Text = 'Low pass fc';
            app.LowpassfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowpassfcEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassfcEditFieldValueChanged, true);
            app.LowpassfcEditField.Value = 200;
            app.EditOrderEditFieldLabel = uilabel(app.UIFigure);
            app.EditOrderEditFieldLabel.Text = 'Edit Order';
            app.EditOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditOrderEditField.ValueChangedFcn = createCallbackFcn(app, @EditOrderEditFieldValueChanged, true);
            app.EditOrderEditField.Value = 4;
            app.LableLabel = uilabel(app.UIFigure);
            app.LableLabel.Text = 'Lable';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.OrderKnobLabel = uilabel(app.UIFigure);
            app.OrderKnobLabel.Text = 'Order';
            app.OrderKnob = uiknob(app.UIFigure, 'discrete');
            app.OrderKnob.Items = {'1', '2', '3', '4'};
            app.OrderKnob.ValueChangedFcn = createCallbackFcn(app, @OrderKnobValueChanged, true);
            app.OrderKnob.Value = '1';
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'Butterworth', 'Chebyshev'};
            app.FilterTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FilterTypeDropDownOpening, true);
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.ClickedFcn = createCallbackFcn(app, @FilterTypeDropDownClicked, true);
            app.FilterTypeDropDown.Value = 'Butterworth';
            app.FilteredSettingsKnobLabel = uilabel(app.UIFigure);
            app.FilteredSettingsKnobLabel.Text = 'Filtered Settings';
            app.FilteredSettingsKnob = uiknob(app.UIFigure, 'discrete');
            app.FilteredSettingsKnob.Items = {'Lowpass', 'Highpass', 'Bandpass', 'Bandstop'};
            app.FilteredSettingsKnob.ValueChangedFcn = createCallbackFcn(app, @FilteredSettingsKnobValueChanged, true);
            app.FilteredSettingsKnob.Value = 'Lowpass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_GUI_FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end